classdef MideDistanciasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        DistanciaEditFieldLabel  matlab.ui.control.Label
        DistanciaEditField       matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IsMeasuring = false; % Flag to check if the app is on measuring
        currentTimer % Timer to get a measure from the camera
        videoInput = videoinput('winvideo',1); % Video input object to interact with the camera
    end
    
    methods (Access = private)
        
        function setupMeasureTimer(app)
            if isempty(app.currentTimer)
                app.currentTimer = timer( ...
                    'StartDelay', 0, ...
                    'Period', 0.1, ...
                    'TasksToExecute', Inf, ...
                    'ExecutionMode', 'fixedRate', ...
                    'UserData', app ...
                );
                app.currentTimer.TimerFcn = @app.measureCallback;
            end
            
            
            timerStatus = get(app.currentTimer, 'Running');
            if ~isequal(timerStatus ,'on')
                start(app.currentTimer);
            end
        end
        
        function stopMeasureTimer(app)
            timerStatus = get(app.currentTimer, 'Running');
            if isequal(timerStatus ,'on')
                stop(app.currentTimer);
            end
        end
        
        function measureCallback(app, ~, ~, ~)
            im = getsnapshot(app.videoInput); 
            im = im(:,:,1);
            
            rel = size(im, 1)/size(im, 2);
            
            app.UIAxes.Position = [app.uiaxes_orig(1) app.uiaxes_orig(2) app.uiaxes_orig(3) app.uiaxes_orig(3)*rel];
            
            imshow(im, 'Parent', app.UIAxes, 'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]);
            drawnow;
            
            r = [129, 21.9999999999999, 59, 137.000000000000];
            
            imROI = im(r(2):r(2)+r(4), r(1):r(1)+r(3));
            
            [row, ~] = find(imROI <= 125);
            mY = mean(row);
            
            interp = interpolacion_sensor(load('medidas2.mat').medidas, 3);
            
            distance = calcula_distancia(interp, mY);
            
            app.DistanciaEditField.Value = string(distance);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.uiaxes_orig_position = app.UIAxes.Position;
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            if app.IsMeasuring
                app.PushTool.Icon = 'play-solid.png';
                app.IsMeasuring = false;
                app.stopMeasureTimer();
            else
                app.PushTool.Icon = 'stop-solid.png';
                app.IsMeasuring = true;
                app.setupMeasureTimer();
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.IsMeasuring = false;
            if ~isempty(app.currentTimer)
                stop(app.currentTimer);
                delete(app.currentTimer);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'play-solid.png';
            app.DistanciaEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.UIFigure, 'text');
            app.DistanciaEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MideDistanciasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end